classdef water_lvl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ResultTextArea                 matlab.ui.control.TextArea
        ResultTextAreaLabel            matlab.ui.control.Label
        PerformanceMetricsLabel        matlab.ui.control.Label
        WaterLevelControlLabel         matlab.ui.control.Label
        DesiredWaterlvlEditField       matlab.ui.control.NumericEditField
        DesiredWaterlvlEditFieldLabel  matlab.ui.control.Label
        SimulateButton                 matlab.ui.control.Button
        DropDown                       matlab.ui.control.DropDown
        DropDownLabel                  matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)

    % Add these 👇
    agent
    env
    end
    
    methods (Access = private)
        
        function metrics = computePerformance(~, t, h, setpoint)

    % Rise Time
    above10 = find(h >= 0.9*setpoint, 1);
    below10 = find(h <= 0.1*setpoint, 1, 'last');
    if ~isempty(above10) && ~isempty(below10)
        riseTime = t(above10) - t(below10);
    else
        riseTime = NaN;
    end

    % Settling Time
    tol = 0.02 * setpoint;
    idx = find(abs(h - setpoint) > tol);
    if isempty(idx)
        settlingTime = 0;
    else
        lastOutOfBounds = idx(end);
        settlingTime = t(lastOutOfBounds);
    end

    % Overshoot
    peak = max(h);
    overshoot = ((peak - setpoint) / setpoint) * 100;

    % MSE
    mse = mean((h - setpoint).^2);

    metrics = struct('RiseTime', riseTime, 'SettlingTime', settlingTime, 'Overshoot', overshoot, 'MSE', mse);
end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function Start_b(app, event)

% Get user input
desiredLevel = app.DesiredWaterlvlEditField.Value;
assignin('base', 'desiredLevel', desiredLevel);  % For RL ResetFcn

% Set simulation parameters
Ts = 1.0;
Tf = 200;
simOpts = rlSimulationOptions('MaxSteps', ceil(Tf/Ts), 'StopOnError','on');

% Load trained agent and environment if not already loaded
if isempty(app.agent) || isempty(app.env)
    s = load('MLtrained.mat', 'agent', 'env');
    app.agent = s.agent;
    app.env = s.env;
end


controllerType = app.DropDown.Value;

if strcmp(controllerType, 'PI')
    % Simulate PI
    simOut_pi = sim('watertank_pi');
    h = simOut_pi.waterlevel.signals.values;
    t = simOut_pi.tout;
    metrics = app.computePerformance(t, h, desiredLevel);

    % Plot
    plot(app.UIAxes, t, h, 'b', 'LineWidth', 2);
    hold(app.UIAxes, 'on');
    yline(app.UIAxes, desiredLevel, '--r', 'Setpoint');
    hold(app.UIAxes, 'off');
    title(app.UIAxes, 'PI Controller Output');
    
elseif strcmp(controllerType, 'RL')
    % Simulate RL
    experiences = sim(app.env, app.agent, simOpts);
    h_ts = experiences.Observation.observations;
    h_all = squeeze(h_ts.Data)';
    h = h_all(:,3);
    t = h_ts.Time;
    metrics = app.computePerformance(t, h, desiredLevel);

    % Plot
    plot(app.UIAxes, t, h, 'g', 'LineWidth', 2);
    hold(app.UIAxes, 'on');
    yline(app.UIAxes, desiredLevel, '--r', 'Setpoint');
    hold(app.UIAxes, 'off');
    title(app.UIAxes, 'RL Controller Output');
    
elseif strcmp(controllerType, 'PI vs RL')
    % Simulate RL
    experiences = sim(app.env, app.agent, simOpts);
    h_ts = experiences.Observation.observations;
    h_all = squeeze(h_ts.Data)';
    h_rl = h_all(:,3);
    t_rl = h_ts.Time;

    % Simulate PI
    simOut_pi = sim('watertank_pi');
    h_pi = simOut_pi.waterlevel.signals.values;
    t_pi = simOut_pi.tout;

    % Match lengths
    minLen = min([length(h_rl), length(h_pi), length(t_rl), length(t_pi)]);
    h_rl = h_rl(1:minLen); t_rl = t_rl(1:minLen);
    h_pi = h_pi(1:minLen); t_pi = t_pi(1:minLen);

    % Compute metrics
    metrics_pi = app.computePerformance(t_pi, h_pi, desiredLevel);
    metrics_rl = app.computePerformance(t_rl, h_rl, desiredLevel);

    % Plot
    plot(app.UIAxes, t_pi, h_pi, 'b', t_rl, h_rl, 'g', 'LineWidth', 2);
    hold(app.UIAxes, 'on');
    yline(app.UIAxes, desiredLevel, '--r', 'Setpoint');
    hold(app.UIAxes, 'off');
    legend(app.UIAxes, 'PI', 'RL', 'Setpoint');
    title(app.UIAxes, 'PI vs RL Comparison');

    % Display both metrics
    msg = {
        '📈 PI Controller Metrics:';
        sprintf('  Rise Time       : %.2fs', metrics_pi.RiseTime);
        sprintf('  Settling Time   : %.2fs', metrics_pi.SettlingTime);
        sprintf('  Overshoot       : %.2f%%', metrics_pi.Overshoot);
        sprintf('  MSE             : %.2f', metrics_pi.MSE);
        '';
        '📈 RL Controller Metrics:';
        sprintf('  Rise Time       : %.2fs', metrics_rl.RiseTime);
        sprintf('  Settling Time   : %.2fs', metrics_rl.SettlingTime);
        sprintf('  Overshoot       : %.2f%%', metrics_rl.Overshoot);
        sprintf('  MSE             : %.2f', metrics_rl.MSE)
    };
    app.ResultTextArea.Value = msg;
end


% Display metrics (example using TextArea)
if exist('metrics', 'var') && ~isempty(metrics) && ~strcmp(app.DropDown.Value, 'PI vs RL')


    app.ResultTextArea.Value = {
    sprintf('📈 Performance Metrics:');
    sprintf('Rise Time       : %.2fs', metrics.RiseTime);
    sprintf('Settling Time   : %.2fs', metrics.SettlingTime);
    sprintf('Overshoot       : %.2f%%', metrics.Overshoot);
    sprintf('Mean Squared Err: %.2f', metrics.MSE);
};


end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {251, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DropDownLabel = uilabel(app.LeftPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'PI', 'RL', 'PI vs RL'};
            app.DropDown.Value = 'PI';
            app.SimulateButton = uibutton(app.LeftPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @Start_b, true);
            app.SimulateButton.Text = 'Simulate';
            app.DesiredWaterlvlEditFieldLabel = uilabel(app.LeftPanel);
            app.DesiredWaterlvlEditFieldLabel.Text = 'Desired Water lvl';
            app.DesiredWaterlvlEditField = uieditfield(app.LeftPanel, 'numeric');
            app.WaterLevelControlLabel = uilabel(app.LeftPanel);
            app.WaterLevelControlLabel.Text = 'Water Level Control';
            app.PerformanceMetricsLabel = uilabel(app.LeftPanel);
            app.PerformanceMetricsLabel.Text = 'Performance Metrics';
            app.ResultTextAreaLabel = uilabel(app.LeftPanel);
            app.ResultTextAreaLabel.Text = 'Result:';
            app.ResultTextArea = uitextarea(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Water Level Performance')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Water Level (m)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = water_lvl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end